classdef bh_4bar_explore_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        bh_4bar_explorer_appUIFigure  matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        LayoutTab                     matlab.ui.container.Tab
        isValidMachineLAMP            matlab.ui.control.Lamp
        IstheMachineValidLabel        matlab.ui.control.Label
        PreconfiguredmachineConfigurationsPanel  matlab.ui.container.Panel
        MACHINEAButton                matlab.ui.control.Button
        MACHINEBButton                matlab.ui.control.Button
        MACHINECButton                matlab.ui.control.Button
        MACHINEDButton                matlab.ui.control.Button
        MACHINEEButton                matlab.ui.control.Button
        MACHINEFButton                matlab.ui.control.Button
        MACHINEGButton                matlab.ui.control.Button
        MACHINEXButton                matlab.ui.control.Button
        TextArea_2                    matlab.ui.control.TextArea
        ManualConfigurationPanel      matlab.ui.container.Panel
        L1EditFieldLabel              matlab.ui.control.Label
        L1EditField                   matlab.ui.control.NumericEditField
        L2EditFieldLabel              matlab.ui.control.Label
        L2EditField                   matlab.ui.control.NumericEditField
        L3EditFieldLabel              matlab.ui.control.Label
        L3EditField                   matlab.ui.control.NumericEditField
        L4EditFieldLabel              matlab.ui.control.Label
        L4EditField                   matlab.ui.control.NumericEditField
        ApplyButton                   matlab.ui.control.Button
        Label_Machine                 matlab.ui.control.Label
        UIAxes_1                      matlab.ui.control.UIAxes
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes_SCHEM_LABEL            matlab.ui.control.UIAxes
        ZTab                          matlab.ui.container.Tab
        TabGroup5                     matlab.ui.container.TabGroup
        NumDenTab                     matlab.ui.container.Tab
        UIAxes_ND_Z1                  matlab.ui.control.UIAxes
        UIAxes_ND_Z2                  matlab.ui.control.UIAxes
        Valid_PoseTab                 matlab.ui.container.Tab
        TabGroup7                     matlab.ui.container.TabGroup
        is_validTab                   matlab.ui.container.Tab
        UIAxes_is_valid               matlab.ui.control.UIAxes
        UIAxes_is_valid_round         matlab.ui.control.UIAxes
        Angles_flatTab                matlab.ui.container.Tab
        TabGroup2                     matlab.ui.container.TabGroup
        Z1Tab                         matlab.ui.container.Tab
        UIAxes_AF_Z1_1                matlab.ui.control.UIAxes
        UIAxes_AF_Z1_2                matlab.ui.control.UIAxes
        UIAxes_AF_Z1_3                matlab.ui.control.UIAxes
        Z2Tab                         matlab.ui.container.Tab
        UIAxes_AF_Z2_1                matlab.ui.control.UIAxes
        UIAxes_AF_Z2_2                matlab.ui.control.UIAxes
        UIAxes_AF_Z2_3                matlab.ui.control.UIAxes
        DET_JacobsTab                 matlab.ui.container.Tab
        UIAxes_DET_and_SOL_1          matlab.ui.control.UIAxes
        UIAxes_DET_and_SOL_2          matlab.ui.control.UIAxes
        Angles_roundTab               matlab.ui.container.Tab
        TabGroup3                     matlab.ui.container.TabGroup
        Z1Tab_2                       matlab.ui.container.Tab
        UIAxes_AR_Z1_1                matlab.ui.control.UIAxes
        UIAxes_AR_Z1_2                matlab.ui.control.UIAxes
        UIAxes_AR_Z1_3                matlab.ui.control.UIAxes
        Z2Tab_2                       matlab.ui.container.Tab
        UIAxes_AR_Z2_1                matlab.ui.control.UIAxes
        UIAxes_AR_Z2_2                matlab.ui.control.UIAxes
        UIAxes_AR_Z2_3                matlab.ui.control.UIAxes
        AnimateTab                    matlab.ui.container.Tab
        TabGroup4                     matlab.ui.container.TabGroup
        CirclesTab                    matlab.ui.container.Tab
        TabGroup6                     matlab.ui.container.TabGroup
        ManualTab                     matlab.ui.container.Tab
        thetadegsKnobLabel            matlab.ui.control.Label
        thetadegsKnob_Circles         matlab.ui.control.Knob
        Label_Circles                 matlab.ui.control.Label
        Image_Circles                 matlab.ui.control.Image
        UIAxes_Circles                matlab.ui.control.UIAxes
        Z1Tab_4                       matlab.ui.container.Tab
        STOPAnimationSwitchLabel_4    matlab.ui.control.Label
        STOP_AN_Z1_CIRC               matlab.ui.control.Switch
        Button_AN_Z1_CIRC             matlab.ui.control.Button
        UIAxes_THETA_Z1_CIRC          matlab.ui.control.UIAxes
        UIAxes_Z1_CIRC                matlab.ui.control.UIAxes
        Z2Tab_4                       matlab.ui.container.Tab
        Button_AN_Z2_CIRC             matlab.ui.control.Button
        STOPAnimationSwitchLabel_5    matlab.ui.control.Label
        STOP_AN_Z2_CIRC               matlab.ui.control.Switch
        UIAxes_THETA_Z2_CIRC          matlab.ui.control.UIAxes
        UIAxes_Z2_CIRC                matlab.ui.control.UIAxes
        Z1Tab_3                       matlab.ui.container.Tab
        Slider_AN_Z1                  matlab.ui.control.Slider
        But_AN_Z1                     matlab.ui.control.Button
        Z1Label                       matlab.ui.control.Label
        STOPAnimationSwitchLabel      matlab.ui.control.Label
        STOP_AN_Z1                    matlab.ui.control.Switch
        ThetadegsEditFieldLabel       matlab.ui.control.Label
        theta_edit_Z1                 matlab.ui.control.NumericEditField
        PhidegsLabel                  matlab.ui.control.Label
        AlphadegsLabel                matlab.ui.control.Label
        Text_phi_Z1                   matlab.ui.control.Label
        Text_alpha_Z1                 matlab.ui.control.Label
        ThetadegsLabel                matlab.ui.control.Label
        Text_theta_Z1                 matlab.ui.control.Label
        UIAxes_AN_Z1_AN               matlab.ui.control.UIAxes
        UIAxes_AN_Z1_THETA            matlab.ui.control.UIAxes
        UIAxes_AN_Z1_PHI              matlab.ui.control.UIAxes
        UIAxes_AN_Z1_ALPHA            matlab.ui.control.UIAxes
        Z2Tab_3                       matlab.ui.container.Tab
        Slider_AN_Z2                  matlab.ui.control.Slider
        But_AN_Z2                     matlab.ui.control.Button
        Z2Label                       matlab.ui.control.Label
        STOPAnimationSwitchLabel_2    matlab.ui.control.Label
        STOP_AN_Z2                    matlab.ui.control.Switch
        ThetadegsEditFieldLabel_2     matlab.ui.control.Label
        theta_edit_Z2                 matlab.ui.control.NumericEditField
        PhidegsLabel_2                matlab.ui.control.Label
        AlphadegsLabel_2              matlab.ui.control.Label
        Text_phi_Z2                   matlab.ui.control.Label
        Text_alpha_Z2                 matlab.ui.control.Label
        ThetadegsLabel_2              matlab.ui.control.Label
        Text_theta_Z2                 matlab.ui.control.Label
        UIAxes_AN_Z2_AN               matlab.ui.control.UIAxes
        UIAxes_AN_Z2_THETA            matlab.ui.control.UIAxes
        UIAxes_AN_Z2_PHI              matlab.ui.control.UIAxes
        UIAxes_AN_Z2_ALPHA            matlab.ui.control.UIAxes
        animation_configTab           matlab.ui.container.Tab
        AnimationPAUSEtimesPanel      matlab.ui.container.Panel
        ValidanglesEditFieldLabel     matlab.ui.control.Label
        PAUSE_ValidanglesEditField    matlab.ui.control.NumericEditField
        INvalidanglesLabel            matlab.ui.control.Label
        PAUSE_INvalidanglesEditField  matlab.ui.control.NumericEditField
        TextArea                      matlab.ui.control.TextArea
        DISPLAYanimationframesPanel   matlab.ui.container.Panel
        DISPLAY_EVERY_N_FRAMES_Knob   matlab.ui.control.DiscreteKnob
        TextArea_3                    matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        OBJ_kin % Description
    end
    
    methods (Access = public)
        
        function results = HLP_new_machine(app)
 
            HLP_clear_app_axes(app);
            
            %% plot to some of the axes
            hax = [app.UIAxes_1,  app.UIAxes_2];
            app.OBJ_kin.plot(hax)
                
            %% plot the Z roots
            %hax = app.UIAxes_Z;
            %app.OBJ_kin.plot_z(hax);
            
            %% plot the Z numerator and Denominator
            hax = [app.UIAxes_ND_Z1, app.UIAxes_ND_Z2];
            app.OBJ_kin.plot_z_num_den(hax);
            
            %% plot the is VALID plot
            hax = [app.UIAxes_is_valid_round, app.UIAxes_is_valid];
            app.OBJ_kin.plot_valid_theta(hax)
            
            
            %% plot the FLAT angles
            hax = [ app.UIAxes_AF_Z1_1,  app.UIAxes_AF_Z1_2, app.UIAxes_AF_Z1_3, ...
                    app.UIAxes_AF_Z2_1,  app.UIAxes_AF_Z2_2, app.UIAxes_AF_Z2_3 ];
            
            app.OBJ_kin.plot_angles_flat(hax);
            
            %% plot the det_jacobs plots
            hax = [app.UIAxes_DET_and_SOL_1, app.UIAxes_DET_and_SOL_2];
            app.OBJ_kin.plot_flat_det_phi_alpha(hax);
                                
            %% plot the ROUND angles
             hax = [ app.UIAxes_AR_Z1_1,  app.UIAxes_AR_Z1_2, app.UIAxes_AR_Z1_3, ...
                     app.UIAxes_AR_Z2_1,  app.UIAxes_AR_Z2_2, app.UIAxes_AR_Z2_3 ];           
            
            app.OBJ_kin.plot_angles_round(hax);
            
            %% plot the static axes on the ANIMATE tab sheet
             hax = [ app.UIAxes_AN_Z1_THETA,  app.UIAxes_AN_Z1_PHI, app.UIAxes_AN_Z1_ALPHA, ...
                     app.UIAxes_AN_Z2_THETA,  app.UIAxes_AN_Z2_PHI, app.UIAxes_AN_Z2_ALPHA ];           
            
            app.OBJ_kin.plot_angles_round(hax);            
            
            %% set the IS_VALID_MACHINE lamp to ORANGE            
            app.isValidMachineLAMP.Color = [0.93, 0.69, 0.1];  % ORANGE
            % pause(1);
            
            %% set the IS_VALID_MACHINE lamp to green
            app.isValidMachineLAMP.Color = [0,1,0];
            
            %% make sure the animation buttons and sliders are enabled
            app.Slider_AN_Z1.Enable = 'On';
            app.Slider_AN_Z2.Enable = 'On';     
            app.But_AN_Z1.Enable    = 'On';
            app.But_AN_Z2.Enable    = 'On';       
            
            % the CIRCLES workspace KNOB
            app.thetadegsKnob_Circles.Value = 0;
            theta_deg = app.thetadegsKnob_Circles.Value;
            
            app.OBJ_kin.plot_circles(theta_deg, app.UIAxes_Circles);
  
            %% the CIRCLES Z1
            app.OBJ_kin.plot_angles_round({app.UIAxes_THETA_Z1_CIRC,NaN,NaN, NaN,NaN,NaN})
            app.OBJ_kin.plot_angles_round({app.UIAxes_THETA_Z2_CIRC,NaN,NaN, NaN,NaN,NaN})  
            
            %%
            % drawnow
        end
        
        function results = HLP_clear_app_axes(app)
           
            % First clear all of the App's AXES
            %cla(app.UIAxes_Z);
            
            % the NUMDEN axes
            cla(app.UIAxes_ND_Z1)
            cla(app.UIAxes_ND_Z2)
            
            cla(app.UIAxes_is_valid) 
            cla(app.UIAxes_is_valid_round) 
            
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_AF_Z1_1);  cla(app.UIAxes_AF_Z1_2);   cla(app.UIAxes_AF_Z1_3);
            cla(app.UIAxes_AF_Z2_1);  cla(app.UIAxes_AF_Z2_2);   cla(app.UIAxes_AF_Z2_3);    
            
            cla(app.UIAxes_AR_Z1_1);  cla(app.UIAxes_AR_Z1_2);   cla(app.UIAxes_AR_Z1_3);
            cla(app.UIAxes_AR_Z2_1);  cla(app.UIAxes_AR_Z2_2);   cla(app.UIAxes_AR_Z2_3);            
            
            cla(app.UIAxes_AN_Z1_AN);  cla(app.UIAxes_AN_Z1_THETA);  cla(app.UIAxes_AN_Z1_PHI);   cla(app.UIAxes_AN_Z1_ALPHA);   
            cla(app.UIAxes_AN_Z2_AN);  cla(app.UIAxes_AN_Z2_THETA);  cla(app.UIAxes_AN_Z2_PHI);   cla(app.UIAxes_AN_Z2_ALPHA);
            
            cla(app.UIAxes_DET_and_SOL_1);
            cla(app.UIAxes_DET_and_SOL_2);
            
            
            set(app.UIAxes_AN_Z1_AN,'Color',[1,1,1]);
            set(app.UIAxes_AN_Z2_AN,'Color',[1,1,1]);
            
            app.Slider_AN_Z1.Value = 0;
            app.Slider_AN_Z2.Value = 0;   
            
            app.theta_edit_Z1.Value = 0;
            app.Text_phi_Z1.Text    = "-------";
            app.Text_alpha_Z1.Text  = "-------";
            app.Text_theta_Z1.Text  = "-------";

            app.theta_edit_Z2.Value = 0;
            app.Text_phi_Z2.Text    = "-------";
            app.Text_alpha_Z2.Text  = "-------";
            app.Text_theta_Z2.Text  = "-------";            
                                  
            %% the CIRCLES Workspace plot
            cla(app.UIAxes_Circles);
            app.Label_Circles.Text = "0";
            
            % clear teh CIRCLES Z1 axes
            cla(app.UIAxes_THETA_Z1_CIRC);
            cla(app.UIAxes_Z1_CIRC);
            cla(app.UIAxes_THETA_Z2_CIRC);
            cla(app.UIAxes_Z2_CIRC);            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            %% add schematic image ti axes
            hax = app.UIAxes_SCHEM_LABEL;
            I = imread("bh_4bar_SCHEM_FOR_APP.PNG");
%             image(hax,I);
%             axis(hax,'equal');
            imshow(I,"Parent",hax)
%            hax.Visible = "off";
            
            hi = app.Image_Circles;
            hi.ImageSource = "bh_4bar_SCHEM_FOR_APP.PNG";

            % choose a machine of interest
             [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_X");
            
            OBJ_kin = bh_4bar_kin_CLS(L1, L2, L3, L4);
            
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();
            
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
           
            L1 = app.L1EditField.Value;
            L2 = app.L2EditField.Value;            
            L3 = app.L3EditField.Value;
            L4 = app.L4EditField.Value;
            
            HLP_clear_app_axes(app);
                        
            try
               OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
          
               app.OBJ_kin = OBJ_kin;
            
              app.HLP_new_machine();
              
            catch
                app.isValidMachineLAMP.Color = [1,0,0];  % RED
            end      

            app.Label_Machine.Text = 'Manual Config';   
        end

        % Value changed function: L1EditField
        function L1EditFieldValueChanged(app, event)
            value = app.L1EditField.Value;
            
            if(value <0)
                value = abs(value);
            end
            
            app.L1EditField.Value = value;
        end

        % Value changed function: L2EditField
        function L2EditFieldValueChanged(app, event)
            value = app.L2EditField.Value;
            
            if(value <0)
                value = abs(value);
            end
            app.L2EditField.Value = value;
        end

        % Value changed function: L3EditField
        function L3EditFieldValueChanged(app, event)
            value = app.L3EditField.Value;

            if(value <0)
                value = abs(value);
            end
            app.L3EditField.Value = value;
        end

        % Value changed function: L4EditField
        function L4EditFieldValueChanged(app, event)
            value = app.L4EditField.Value;
            
            if(value <0)
                value = abs(value);
            end
            app.L4EditField.Value = value;
        end

        % Button pushed function: But_AN_Z1
        function But_AN_Z1ButtonPushed(app, event)
            
            app.OBJ_kin.animate_for_app("FIRST", app)
            
        end

        % Value changed function: Slider_AN_Z1
        function Slider_AN_Z1ValueChanged(app, event)
            value = app.Slider_AN_Z1.Value;
            
             app.OBJ_kin.slider_for_app("FIRST", app)
        end

        % Button pushed function: But_AN_Z2
        function But_AN_Z2ButtonPushed(app, event)
             app.OBJ_kin.animate_for_app("SECOND", app)
        end

        % Value changed function: Slider_AN_Z2
        function Slider_AN_Z2ValueChanged(app, event)
            value = app.Slider_AN_Z2.Value;
            
            app.OBJ_kin.slider_for_app("SECOND", app)
        end

        % Button pushed function: MACHINEAButton
        function MACHINEAButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_A");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
                       
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();
            
            app.Label_Machine.Text = 'MACHINE  A';
        end

        % Button pushed function: MACHINEBButton
        function MACHINEBButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_B");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  B';            
        end

        % Value changed function: theta_edit_Z1
        function theta_edit_Z1ValueChanged(app, event)
            value = app.theta_edit_Z1.Value;
            
            if(value > 180)
                value = 180;
            end
            
            if(value < -180)
                value = -180;
            end
            
            app.theta_edit_Z1.Value = value;
            
            app.Slider_AN_Z1.Value   = value;
            
            app.OBJ_kin.slider_for_app("FIRST", app)
        end

        % Value changed function: theta_edit_Z2
        function theta_edit_Z2ValueChanged(app, event)
            value = app.theta_edit_Z2.Value;
 
            if(value > 180)
                value = 180;
            end
            
            if(value < -180)
                value = -180;
            end
            
            app.theta_edit_Z2.Value = value;
            
            app.Slider_AN_Z2.Value   = value;
            
            app.OBJ_kin.slider_for_app("SECOND", app)            
        end

        % Button pushed function: MACHINECButton
        function MACHINECButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_C");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  C';                     
        end

        % Button pushed function: MACHINEDButton
        function MACHINEDButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_D");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  D';                    
        end

        % Button pushed function: MACHINEEButton
        function MACHINEEButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_E");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  E';                           
        end

        % Button pushed function: MACHINEFButton
        function MACHINEFButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_F");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  F';                             
        end

        % Button pushed function: MACHINEGButton
        function MACHINEGButtonPushed(app, event)
          
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_G");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  G';                      
        end

        % Value changed function: thetadegsKnob_Circles
        function thetadegsKnob_CirclesValueChanged(app, event)
            value = app.thetadegsKnob_Circles.Value;
            theta_deg = value;
            app.OBJ_kin.plot_circles(theta_deg, app.UIAxes_Circles);       
            
            app.Label_Circles.Text = sprintf('%.1f', theta_deg);
        end

        % Button pushed function: Button_AN_Z1_CIRC
        function Button_AN_Z1_CIRCPushed(app, event)
            app.OBJ_kin.animate_with_circles_for_app("FIRST",  app);
        end

        % Button pushed function: Button_AN_Z2_CIRC
        function Button_AN_Z2_CIRCPushed(app, event)
           app.OBJ_kin.animate_with_circles_for_app("SECOND",  app); 
        end

        % Button pushed function: MACHINEXButton
        function MACHINEXButtonPushed(app, event)
            [L1, L2, L3, L4] = bh_get_preconfigured_machine("MACHINE_X");
                                  
            OBJ_kin = bh_4bar_kin_CLS(L1,L2,L3,L4);
            
            app.L1EditField.Value = OBJ_kin.L1;
            app.L2EditField.Value = OBJ_kin.L2;
            app.L3EditField.Value = OBJ_kin.L3;            
            app.L4EditField.Value = OBJ_kin.L4;        
            
            app.OBJ_kin = OBJ_kin;
            
            app.HLP_new_machine();      

            app.Label_Machine.Text = 'MACHINE  X';            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.bh_4bar_explorer_appUIFigure = uifigure('Visible', 'off');
            app.bh_4bar_explorer_appUIFigure.Name = 'bh_4bar_explorer_app';
            app.TabGroup = uitabgroup(app.bh_4bar_explorer_appUIFigure);
            app.LayoutTab = uitab(app.TabGroup);
            app.LayoutTab.Title = 'Layout';
            app.isValidMachineLAMP = uilamp(app.LayoutTab);
            app.IstheMachineValidLabel = uilabel(app.LayoutTab);
            app.IstheMachineValidLabel.Text = 'Is the Machine Valid';
            app.PreconfiguredmachineConfigurationsPanel = uipanel(app.LayoutTab);
            app.PreconfiguredmachineConfigurationsPanel.Title = 'Preconfigured machine Configurations';
            app.MACHINEAButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEAButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEAButtonPushed, true);
            app.MACHINEAButton.Text = 'MACHINE A';
            app.MACHINEBButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEBButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEBButtonPushed, true);
            app.MACHINEBButton.Text = 'MACHINE B';
            app.MACHINECButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINECButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINECButtonPushed, true);
            app.MACHINECButton.Text = {'MACHINE C'; ''};
            app.MACHINEDButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEDButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEDButtonPushed, true);
            app.MACHINEDButton.Text = {'MACHINE D'; ''};
            app.MACHINEEButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEEButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEEButtonPushed, true);
            app.MACHINEEButton.Text = {'MACHINE E'; ''};
            app.MACHINEFButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEFButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEFButtonPushed, true);
            app.MACHINEFButton.Text = {'MACHINE F'; ''};
            app.MACHINEGButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEGButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEGButtonPushed, true);
            app.MACHINEGButton.Text = {'MACHINE G'; ''};
            app.MACHINEXButton = uibutton(app.PreconfiguredmachineConfigurationsPanel, 'push');
            app.MACHINEXButton.ButtonPushedFcn = createCallbackFcn(app, @MACHINEXButtonPushed, true);
            app.MACHINEXButton.Text = {'MACHINE X'; ''};
            app.TextArea_2 = uitextarea(app.LayoutTab);
            app.TextArea_2.Value = {'See the script bh_4bar_analysis_kinematics for a discussion on what we mean by a "VALID" machine.'};
            app.ManualConfigurationPanel = uipanel(app.LayoutTab);
            app.ManualConfigurationPanel.Title = 'Manual Configuration';
            app.L1EditFieldLabel = uilabel(app.ManualConfigurationPanel);
            app.L1EditFieldLabel.Text = 'L1';
            app.L1EditField = uieditfield(app.ManualConfigurationPanel, 'numeric');
            app.L1EditField.ValueChangedFcn = createCallbackFcn(app, @L1EditFieldValueChanged, true);
            app.L2EditFieldLabel = uilabel(app.ManualConfigurationPanel);
            app.L2EditFieldLabel.Text = 'L2';
            app.L2EditField = uieditfield(app.ManualConfigurationPanel, 'numeric');
            app.L2EditField.ValueChangedFcn = createCallbackFcn(app, @L2EditFieldValueChanged, true);
            app.L3EditFieldLabel = uilabel(app.ManualConfigurationPanel);
            app.L3EditFieldLabel.Text = 'L3';
            app.L3EditField = uieditfield(app.ManualConfigurationPanel, 'numeric');
            app.L3EditField.ValueChangedFcn = createCallbackFcn(app, @L3EditFieldValueChanged, true);
            app.L4EditFieldLabel = uilabel(app.ManualConfigurationPanel);
            app.L4EditFieldLabel.Text = 'L4';
            app.L4EditField = uieditfield(app.ManualConfigurationPanel, 'numeric');
            app.L4EditField.ValueChangedFcn = createCallbackFcn(app, @L4EditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.ManualConfigurationPanel, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            app.Label_Machine = uilabel(app.LayoutTab);
            app.Label_Machine.Text = 'Manual Config';
            app.UIAxes_1 = uiaxes(app.LayoutTab);
            title(app.UIAxes_1, 'Title')
            xlabel(app.UIAxes_1, 'X')
            ylabel(app.UIAxes_1, 'Y')
            app.UIAxes_2 = uiaxes(app.LayoutTab);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            app.UIAxes_SCHEM_LABEL = uiaxes(app.LayoutTab);
            app.ZTab = uitab(app.TabGroup);
            app.ZTab.Title = 'Z';
            app.TabGroup5 = uitabgroup(app.ZTab);
            app.NumDenTab = uitab(app.TabGroup5);
            app.NumDenTab.Title = 'NumDen';
            app.UIAxes_ND_Z1 = uiaxes(app.NumDenTab);
            title(app.UIAxes_ND_Z1, 'Title')
            xlabel(app.UIAxes_ND_Z1, 'X')
            ylabel(app.UIAxes_ND_Z1, 'Y')
            app.UIAxes_ND_Z2 = uiaxes(app.NumDenTab);
            title(app.UIAxes_ND_Z2, 'Title')
            xlabel(app.UIAxes_ND_Z2, 'X')
            ylabel(app.UIAxes_ND_Z2, 'Y')
            app.Valid_PoseTab = uitab(app.TabGroup);
            app.Valid_PoseTab.Title = 'Valid_Pose';
            app.TabGroup7 = uitabgroup(app.Valid_PoseTab);
            app.is_validTab = uitab(app.TabGroup7);
            app.is_validTab.Title = 'is_valid';
            app.UIAxes_is_valid = uiaxes(app.is_validTab);
            title(app.UIAxes_is_valid, 'Is VALID pose')
            xlabel(app.UIAxes_is_valid, '\theta (deg)')
            ylabel(app.UIAxes_is_valid, 'IS VALID')
            app.UIAxes_is_valid_round = uiaxes(app.is_validTab);
            title(app.UIAxes_is_valid_round, 'RED Link')
            xlabel(app.UIAxes_is_valid_round, 'X')
            ylabel(app.UIAxes_is_valid_round, 'Y')
            app.Angles_flatTab = uitab(app.TabGroup);
            app.Angles_flatTab.Title = 'Angles_flat';
            app.TabGroup2 = uitabgroup(app.Angles_flatTab);
            app.Z1Tab = uitab(app.TabGroup2);
            app.Z1Tab.Title = 'Z1';
            app.UIAxes_AF_Z1_1 = uiaxes(app.Z1Tab);
            title(app.UIAxes_AF_Z1_1, 'Title')
            xlabel(app.UIAxes_AF_Z1_1, 'X')
            ylabel(app.UIAxes_AF_Z1_1, 'Y')
            app.UIAxes_AF_Z1_2 = uiaxes(app.Z1Tab);
            title(app.UIAxes_AF_Z1_2, 'Title')
            xlabel(app.UIAxes_AF_Z1_2, 'X')
            ylabel(app.UIAxes_AF_Z1_2, 'Y')
            app.UIAxes_AF_Z1_3 = uiaxes(app.Z1Tab);
            title(app.UIAxes_AF_Z1_3, 'Title')
            xlabel(app.UIAxes_AF_Z1_3, 'X')
            ylabel(app.UIAxes_AF_Z1_3, 'Y')
            app.Z2Tab = uitab(app.TabGroup2);
            app.Z2Tab.Title = 'Z2';
            app.UIAxes_AF_Z2_1 = uiaxes(app.Z2Tab);
            title(app.UIAxes_AF_Z2_1, 'Title')
            xlabel(app.UIAxes_AF_Z2_1, 'X')
            ylabel(app.UIAxes_AF_Z2_1, 'Y')
            app.UIAxes_AF_Z2_2 = uiaxes(app.Z2Tab);
            title(app.UIAxes_AF_Z2_2, 'Title')
            xlabel(app.UIAxes_AF_Z2_2, 'X')
            ylabel(app.UIAxes_AF_Z2_2, 'Y')
            app.UIAxes_AF_Z2_3 = uiaxes(app.Z2Tab);
            title(app.UIAxes_AF_Z2_3, 'Title')
            xlabel(app.UIAxes_AF_Z2_3, 'X')
            ylabel(app.UIAxes_AF_Z2_3, 'Y')
            app.DET_JacobsTab = uitab(app.TabGroup2);
            app.DET_JacobsTab.Title = 'DET_Jacobs';
            app.UIAxes_DET_and_SOL_1 = uiaxes(app.DET_JacobsTab);
            title(app.UIAxes_DET_and_SOL_1, 'Title')
            xlabel(app.UIAxes_DET_and_SOL_1, 'X')
            ylabel(app.UIAxes_DET_and_SOL_1, 'Y')
            app.UIAxes_DET_and_SOL_2 = uiaxes(app.DET_JacobsTab);
            title(app.UIAxes_DET_and_SOL_2, 'Title')
            xlabel(app.UIAxes_DET_and_SOL_2, 'X')
            ylabel(app.UIAxes_DET_and_SOL_2, 'Y')
            app.Angles_roundTab = uitab(app.TabGroup);
            app.Angles_roundTab.Title = 'Angles_round';
            app.TabGroup3 = uitabgroup(app.Angles_roundTab);
            app.Z1Tab_2 = uitab(app.TabGroup3);
            app.Z1Tab_2.Title = 'Z1';
            app.UIAxes_AR_Z1_1 = uiaxes(app.Z1Tab_2);
            title(app.UIAxes_AR_Z1_1, 'Title')
            xlabel(app.UIAxes_AR_Z1_1, 'X')
            ylabel(app.UIAxes_AR_Z1_1, 'Y')
            app.UIAxes_AR_Z1_2 = uiaxes(app.Z1Tab_2);
            title(app.UIAxes_AR_Z1_2, 'Title')
            xlabel(app.UIAxes_AR_Z1_2, 'X')
            ylabel(app.UIAxes_AR_Z1_2, 'Y')
            app.UIAxes_AR_Z1_3 = uiaxes(app.Z1Tab_2);
            title(app.UIAxes_AR_Z1_3, 'Title')
            xlabel(app.UIAxes_AR_Z1_3, 'X')
            ylabel(app.UIAxes_AR_Z1_3, 'Y')
            app.Z2Tab_2 = uitab(app.TabGroup3);
            app.Z2Tab_2.Title = 'Z2';
            app.UIAxes_AR_Z2_1 = uiaxes(app.Z2Tab_2);
            title(app.UIAxes_AR_Z2_1, 'Title')
            xlabel(app.UIAxes_AR_Z2_1, 'X')
            ylabel(app.UIAxes_AR_Z2_1, 'Y')
            app.UIAxes_AR_Z2_2 = uiaxes(app.Z2Tab_2);
            title(app.UIAxes_AR_Z2_2, 'Title')
            xlabel(app.UIAxes_AR_Z2_2, 'X')
            ylabel(app.UIAxes_AR_Z2_2, 'Y')
            app.UIAxes_AR_Z2_3 = uiaxes(app.Z2Tab_2);
            title(app.UIAxes_AR_Z2_3, 'Title')
            xlabel(app.UIAxes_AR_Z2_3, 'X')
            ylabel(app.UIAxes_AR_Z2_3, 'Y')
            app.AnimateTab = uitab(app.TabGroup);
            app.AnimateTab.Title = 'Animate';
            app.TabGroup4 = uitabgroup(app.AnimateTab);
            app.CirclesTab = uitab(app.TabGroup4);
            app.CirclesTab.Title = 'Circles';
            app.TabGroup6 = uitabgroup(app.CirclesTab);
            app.ManualTab = uitab(app.TabGroup6);
            app.ManualTab.Title = 'Manual';
            app.thetadegsKnobLabel = uilabel(app.ManualTab);
            app.thetadegsKnobLabel.Text = 'theta (degs)';
            app.thetadegsKnob_Circles = uiknob(app.ManualTab, 'continuous');
            app.thetadegsKnob_Circles.Limits = [-180 180];
            app.thetadegsKnob_Circles.MajorTicks = [-180 -165 -150 -135 -120 -105 -90 -75 -60 -45 -30 -15 0 15 30 45 60 75 90 105 120 135 150 165 180];
            app.thetadegsKnob_Circles.ValueChangedFcn = createCallbackFcn(app, @thetadegsKnob_CirclesValueChanged, true);
            app.Label_Circles = uilabel(app.ManualTab);
            app.Image_Circles = uiimage(app.ManualTab);
            app.UIAxes_Circles = uiaxes(app.ManualTab);
            xlabel(app.UIAxes_Circles, 'X')
            ylabel(app.UIAxes_Circles, 'Y')
            app.Z1Tab_4 = uitab(app.TabGroup6);
            app.Z1Tab_4.Title = 'Z1';
            app.STOPAnimationSwitchLabel_4 = uilabel(app.Z1Tab_4);
            app.STOPAnimationSwitchLabel_4.Text = 'STOP Animation';
            app.STOP_AN_Z1_CIRC = uiswitch(app.Z1Tab_4, 'slider');
            app.STOP_AN_Z1_CIRC.Items = {'YES', 'NO'};
            app.STOP_AN_Z1_CIRC.Value = 'NO';
            app.Button_AN_Z1_CIRC = uibutton(app.Z1Tab_4, 'push');
            app.Button_AN_Z1_CIRC.ButtonPushedFcn = createCallbackFcn(app, @Button_AN_Z1_CIRCPushed, true);
            app.Button_AN_Z1_CIRC.Text = 'Animate';
            app.UIAxes_THETA_Z1_CIRC = uiaxes(app.Z1Tab_4);
            title(app.UIAxes_THETA_Z1_CIRC, 'Title')
            xlabel(app.UIAxes_THETA_Z1_CIRC, 'X')
            ylabel(app.UIAxes_THETA_Z1_CIRC, 'Y')
            app.UIAxes_Z1_CIRC = uiaxes(app.Z1Tab_4);
            title(app.UIAxes_Z1_CIRC, 'Z1 solution')
            xlabel(app.UIAxes_Z1_CIRC, 'X')
            ylabel(app.UIAxes_Z1_CIRC, 'Y')
            app.Z2Tab_4 = uitab(app.TabGroup6);
            app.Z2Tab_4.Title = 'Z2';
            app.Button_AN_Z2_CIRC = uibutton(app.Z2Tab_4, 'push');
            app.Button_AN_Z2_CIRC.ButtonPushedFcn = createCallbackFcn(app, @Button_AN_Z2_CIRCPushed, true);
            app.Button_AN_Z2_CIRC.Text = 'Animate';
            app.STOPAnimationSwitchLabel_5 = uilabel(app.Z2Tab_4);
            app.STOPAnimationSwitchLabel_5.Text = 'STOP Animation';
            app.STOP_AN_Z2_CIRC = uiswitch(app.Z2Tab_4, 'slider');
            app.STOP_AN_Z2_CIRC.Items = {'YES', 'NO'};
            app.STOP_AN_Z2_CIRC.Value = 'NO';
            app.UIAxes_THETA_Z2_CIRC = uiaxes(app.Z2Tab_4);
            title(app.UIAxes_THETA_Z2_CIRC, 'Title')
            xlabel(app.UIAxes_THETA_Z2_CIRC, 'X')
            ylabel(app.UIAxes_THETA_Z2_CIRC, 'Y')
            app.UIAxes_Z2_CIRC = uiaxes(app.Z2Tab_4);
            title(app.UIAxes_Z2_CIRC, 'Z2 solution')
            xlabel(app.UIAxes_Z2_CIRC, 'X')
            ylabel(app.UIAxes_Z2_CIRC, 'Y')
            app.Z1Tab_3 = uitab(app.TabGroup4);
            app.Z1Tab_3.Title = 'Z1';
            app.Slider_AN_Z1 = uislider(app.Z1Tab_3);
            app.Slider_AN_Z1.Limits = [-180 180];
            app.Slider_AN_Z1.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.Slider_AN_Z1.ValueChangedFcn = createCallbackFcn(app, @Slider_AN_Z1ValueChanged, true);
            app.But_AN_Z1 = uibutton(app.Z1Tab_3, 'push');
            app.But_AN_Z1.ButtonPushedFcn = createCallbackFcn(app, @But_AN_Z1ButtonPushed, true);
            app.But_AN_Z1.Text = {'ANIMATE'; ''};
            app.Z1Label = uilabel(app.Z1Tab_3);
            app.Z1Label.Text = 'Z1';
            app.STOPAnimationSwitchLabel = uilabel(app.Z1Tab_3);
            app.STOPAnimationSwitchLabel.Text = 'STOP Animation';
            app.STOP_AN_Z1 = uiswitch(app.Z1Tab_3, 'slider');
            app.STOP_AN_Z1.Items = {'YES', 'NO'};
            app.STOP_AN_Z1.Value = 'NO';
            app.ThetadegsEditFieldLabel = uilabel(app.Z1Tab_3);
            app.ThetadegsEditFieldLabel.Text = 'Theta (degs)';
            app.theta_edit_Z1 = uieditfield(app.Z1Tab_3, 'numeric');
            app.theta_edit_Z1.ValueChangedFcn = createCallbackFcn(app, @theta_edit_Z1ValueChanged, true);
            app.PhidegsLabel = uilabel(app.Z1Tab_3);
            app.PhidegsLabel.Text = 'Phi (degs)';
            app.AlphadegsLabel = uilabel(app.Z1Tab_3);
            app.AlphadegsLabel.Text = 'Alpha (degs)';
            app.Text_phi_Z1 = uilabel(app.Z1Tab_3);
            app.Text_phi_Z1.Text = '-------';
            app.Text_alpha_Z1 = uilabel(app.Z1Tab_3);
            app.Text_alpha_Z1.Text = '-------';
            app.ThetadegsLabel = uilabel(app.Z1Tab_3);
            app.ThetadegsLabel.Text = 'Theta (degs)';
            app.Text_theta_Z1 = uilabel(app.Z1Tab_3);
            app.Text_theta_Z1.Text = '-------';
            app.UIAxes_AN_Z1_AN = uiaxes(app.Z1Tab_3);
            title(app.UIAxes_AN_Z1_AN, 'Z1 solution')
            xlabel(app.UIAxes_AN_Z1_AN, 'X')
            ylabel(app.UIAxes_AN_Z1_AN, 'Y')
            app.UIAxes_AN_Z1_THETA = uiaxes(app.Z1Tab_3);
            title(app.UIAxes_AN_Z1_THETA, 'Title')
            xlabel(app.UIAxes_AN_Z1_THETA, 'X')
            ylabel(app.UIAxes_AN_Z1_THETA, 'Y')
            app.UIAxes_AN_Z1_PHI = uiaxes(app.Z1Tab_3);
            title(app.UIAxes_AN_Z1_PHI, 'Title')
            xlabel(app.UIAxes_AN_Z1_PHI, 'X')
            ylabel(app.UIAxes_AN_Z1_PHI, 'Y')
            app.UIAxes_AN_Z1_ALPHA = uiaxes(app.Z1Tab_3);
            title(app.UIAxes_AN_Z1_ALPHA, 'Title')
            xlabel(app.UIAxes_AN_Z1_ALPHA, 'X')
            ylabel(app.UIAxes_AN_Z1_ALPHA, 'Y')
            app.Z2Tab_3 = uitab(app.TabGroup4);
            app.Z2Tab_3.Title = 'Z2';
            app.Slider_AN_Z2 = uislider(app.Z2Tab_3);
            app.Slider_AN_Z2.Limits = [-180 180];
            app.Slider_AN_Z2.MajorTicks = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.Slider_AN_Z2.ValueChangedFcn = createCallbackFcn(app, @Slider_AN_Z2ValueChanged, true);
            app.But_AN_Z2 = uibutton(app.Z2Tab_3, 'push');
            app.But_AN_Z2.ButtonPushedFcn = createCallbackFcn(app, @But_AN_Z2ButtonPushed, true);
            app.But_AN_Z2.Text = {'ANIMATE'; ''};
            app.Z2Label = uilabel(app.Z2Tab_3);
            app.Z2Label.Text = 'Z2';
            app.STOPAnimationSwitchLabel_2 = uilabel(app.Z2Tab_3);
            app.STOPAnimationSwitchLabel_2.Text = 'STOP Animation';
            app.STOP_AN_Z2 = uiswitch(app.Z2Tab_3, 'slider');
            app.STOP_AN_Z2.Items = {'YES', 'NO'};
            app.STOP_AN_Z2.Value = 'NO';
            app.ThetadegsEditFieldLabel_2 = uilabel(app.Z2Tab_3);
            app.ThetadegsEditFieldLabel_2.Text = 'Theta (degs)';
            app.theta_edit_Z2 = uieditfield(app.Z2Tab_3, 'numeric');
            app.theta_edit_Z2.ValueChangedFcn = createCallbackFcn(app, @theta_edit_Z2ValueChanged, true);
            app.PhidegsLabel_2 = uilabel(app.Z2Tab_3);
            app.PhidegsLabel_2.Text = 'Phi (degs)';
            app.AlphadegsLabel_2 = uilabel(app.Z2Tab_3);
            app.AlphadegsLabel_2.Text = 'Alpha (degs)';
            app.Text_phi_Z2 = uilabel(app.Z2Tab_3);
            app.Text_phi_Z2.Text = '-------';
            app.Text_alpha_Z2 = uilabel(app.Z2Tab_3);
            app.Text_alpha_Z2.Text = '-------';
            app.ThetadegsLabel_2 = uilabel(app.Z2Tab_3);
            app.ThetadegsLabel_2.Text = 'Theta (degs)';
            app.Text_theta_Z2 = uilabel(app.Z2Tab_3);
            app.Text_theta_Z2.Text = '-------';
            app.UIAxes_AN_Z2_AN = uiaxes(app.Z2Tab_3);
            title(app.UIAxes_AN_Z2_AN, 'Z2 solution')
            xlabel(app.UIAxes_AN_Z2_AN, 'X')
            ylabel(app.UIAxes_AN_Z2_AN, 'Y')
            app.UIAxes_AN_Z2_THETA = uiaxes(app.Z2Tab_3);
            title(app.UIAxes_AN_Z2_THETA, 'Title')
            xlabel(app.UIAxes_AN_Z2_THETA, 'X')
            ylabel(app.UIAxes_AN_Z2_THETA, 'Y')
            app.UIAxes_AN_Z2_PHI = uiaxes(app.Z2Tab_3);
            title(app.UIAxes_AN_Z2_PHI, 'Title')
            xlabel(app.UIAxes_AN_Z2_PHI, 'X')
            ylabel(app.UIAxes_AN_Z2_PHI, 'Y')
            app.UIAxes_AN_Z2_ALPHA = uiaxes(app.Z2Tab_3);
            title(app.UIAxes_AN_Z2_ALPHA, 'Title')
            xlabel(app.UIAxes_AN_Z2_ALPHA, 'X')
            ylabel(app.UIAxes_AN_Z2_ALPHA, 'Y')
            app.animation_configTab = uitab(app.TabGroup4);
            app.animation_configTab.Title = 'animation_config';
            app.AnimationPAUSEtimesPanel = uipanel(app.animation_configTab);
            app.AnimationPAUSEtimesPanel.Title = 'Animation PAUSE times';
            app.ValidanglesEditFieldLabel = uilabel(app.AnimationPAUSEtimesPanel);
            app.ValidanglesEditFieldLabel.Text = 'Valid angles ';
            app.PAUSE_ValidanglesEditField = uieditfield(app.AnimationPAUSEtimesPanel, 'numeric');
            app.INvalidanglesLabel = uilabel(app.AnimationPAUSEtimesPanel);
            app.INvalidanglesLabel.Text = 'INvalid angles';
            app.PAUSE_INvalidanglesEditField = uieditfield(app.AnimationPAUSEtimesPanel, 'numeric');
            app.PAUSE_INvalidanglesEditField.Value = 10;
            app.TextArea = uitextarea(app.AnimationPAUSEtimesPanel);
            app.TextArea.Value = {'Specify the pause time in milliseconds between frame animations'; ''};
            app.DISPLAYanimationframesPanel = uipanel(app.animation_configTab);
            app.DISPLAYanimationframesPanel.Title = 'DISPLAY animation frames';
            app.DISPLAY_EVERY_N_FRAMES_Knob = uiknob(app.DISPLAYanimationframesPanel, 'discrete');
            app.DISPLAY_EVERY_N_FRAMES_Knob.Items = {'1', '2', '3', '5', '10'};
            app.DISPLAY_EVERY_N_FRAMES_Knob.Value = '3';
            app.TextArea_3 = uitextarea(app.DISPLAYanimationframesPanel);
            app.TextArea_3.Value = {'Specify how often you want to DISPLAY the animation frames, eg:dsplay every 5th frame, every 10th frame, '; ''};
            % Show the figure after all components are created
            app.bh_4bar_explorer_appUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = bh_4bar_explore_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.bh_4bar_explorer_appUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.bh_4bar_explorer_appUIFigure)
        end
    end
end